classdef PRAC_1_Voz_Equipo2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        AccionesLabel              matlab.ui.control.Label
        FuncionesLabel             matlab.ui.control.Label
        CBsumaSenales              matlab.ui.control.CheckBox
        CBtriangular               matlab.ui.control.CheckBox
        CBcuadrada                 matlab.ui.control.CheckBox
        CBruidoSenales             matlab.ui.control.CheckBox
        CBcoseno                   matlab.ui.control.CheckBox
        CBseno                     matlab.ui.control.CheckBox
        ActualizarDatosButton      matlab.ui.control.Button
        milisegundosLabel          matlab.ui.control.Label
        tiempo                     matlab.ui.control.NumericEditField
        TiempoLabel                matlab.ui.control.Label
        HzLabel_2                  matlab.ui.control.Label
        fmuestreo                  matlab.ui.control.NumericEditField
        FrecuenciademuestreoLabel  matlab.ui.control.Label
        gradosLabel                matlab.ui.control.Label
        desfase                    matlab.ui.control.NumericEditField
        DesfaseLabel               matlab.ui.control.Label
        HzLabel                    matlab.ui.control.Label
        frecuencia                 matlab.ui.control.NumericEditField
        FrecuenciaLabel            matlab.ui.control.Label
        amplitud                   matlab.ui.control.Spinner
        AmplitudLabel              matlab.ui.control.Label
        SealesLabel                matlab.ui.control.Label
        axeFase                    matlab.ui.control.UIAxes
        axeFFT                     matlab.ui.control.UIAxes
        axeFFTcentrada             matlab.ui.control.UIAxes
        axeNormal                  matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: amplitud
        function amplitudValueChanged(app, event)


        end

        % Callback function: ActualizarDatosButton, CBcoseno, CBcuadrada, 
        % ...and 4 other components
        function GraficarButtonPushed(app, event)
           global A f des fs tiempo;
            cla(app.axeNormal, "reset")
            cla(app.axeFFTcentrada, "reset") 
            cla(app.axeFFT, "reset") 
            cla(app.axeFase, "reset") 
            
            A = app.amplitud.Value;
            f = app.frecuencia.Value;
            des = app.desfase.Value;
            fs = app.fmuestreo.Value;
            tiempo = app.tiempo.Value;
            
            T = tiempo / 1000;
            Ts = 1 / fs;
            t = 0:Ts:T-Ts;
            N = length(t);
            
            freqs = (0:N-1)*(fs/N);
            freqs_central = (-N/2:N/2-1)*(fs/N);
            
            x_sum = zeros(size(t));
            fft_x_sum = zeros(size(freqs));
            x_sum_freqs_central = zeros(size(freqs_central));
            x_sum_fase = zeros(size(freqs));
            
            % Lista de señales
            signals = {
                'seno', @sin, app.CBseno.Value, 'b';
                'coseno', @cos, app.CBcoseno.Value, 'r';
                'cuadrada', @square, app.CBcuadrada.Value, 'g';
                'triangular', @sawtooth, app.CBtriangular.Value, 'm';
            };
            
            for i = 1:size(signals, 1)
                if signals{i, 3} == 1
                    x = A * signals{i, 2}(2 * pi * f * t + deg2rad(des));
                    
                    % Aplicar ruido si es necesario
                    if app.CBruidoSenales.Value == 1
                        x = x + randn(size(x));
                    end
            
                    % FFT y gráficos
                    [fft_x, fft_centrada_x, fase_x] = calculateFFT(x, N, fs);
            
                    fft_x_sum = fft_x_sum + fft_x;
                    x_sum_freqs_central = x_sum_freqs_central + fft_centrada_x;
                    x_sum_fase = x_sum_fase + fase_x;
            
                    plotSignals(t, x, freqs, fft_x, freqs_central, fft_centrada_x, fase_x, signals{i, 4}, app);
                    
                    x_sum = x_sum + x;
                end
            end
            
            % Suma de señales
            if app.CBsumaSenales.Value == 1
                if app.CBruidoSenales.Value == 1
                    x_sum = x_sum + randn(size(x_sum));
                end
                plotSignals(t, x_sum, freqs, fft_x_sum, freqs_central, x_sum_freqs_central, x_sum_fase, 'k', app);
            end
            
            % Leyenda dinámica
            createLegend(signals, app);
            
            % Funciones auxiliares
            function [fft_x, fft_centrada_x, fase_x] = calculateFFT(x, N, fs)
                fft_x = fft(x);
                fft_centrada_x = fftshift(fft_x);
                fase_x = angle(fft_centrada_x);
            end
            
            function plotSignals(t, x, freqs, fft_x, freqs_central, fft_centrada_x, fase_x, color, app)
                plot(app.axeNormal, t, x, color, 'LineWidth', 1.5);
                title(app.axeNormal,'Normal');
                hold(app.axeNormal, 'on');
                plot(app.axeFFT, freqs, abs(fft_x) / length(t), color, 'LineWidth', 1.5);
                title(app.axeFFT,'FFT');
                hold(app.axeFFT, 'on');
                plot(app.axeFFTcentrada, freqs_central, abs(fft_centrada_x) / length(t), color, 'LineWidth', 1.5);
                title(app.axeFFTcentrada,'FFT centrada');
                hold(app.axeFFTcentrada, 'on');
                plot(app.axeFase, freqs_central, fase_x, color, 'LineWidth', 1.5);
                title(app.axeFase,'Fase ');
                hold(app.axeFase, 'on');
            end
            
            function createLegend(signals, app)
                legendList = {};
                for i = 1:size(signals, 1)
                    if signals{i, 3} == 1
                        legendList{end+1} = ['Señal ' signals{i, 1}];
                    end
                end
                if app.CBsumaSenales.Value == 1
                    legendList{end+1} = 'Suma de Señales';
                end
                legend(app.axeNormal, legendList);
                hold(app.axeNormal, 'off');
                hold(app.axeFFTcentrada, 'off');
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.axeNormal = uiaxes(app.UIFigure);
            title(app.axeNormal, 'Normal')
            xlabel(app.axeNormal, 'X')
            ylabel(app.axeNormal, 'Y')
            zlabel(app.axeNormal, 'Z')
            app.axeFFTcentrada = uiaxes(app.UIFigure);
            title(app.axeFFTcentrada, 'FFT Centrada')
            xlabel(app.axeFFTcentrada, 'X')
            ylabel(app.axeFFTcentrada, 'Y')
            zlabel(app.axeFFTcentrada, 'Z')
            app.axeFFT = uiaxes(app.UIFigure);
            title(app.axeFFT, 'FFT')
            xlabel(app.axeFFT, 'X')
            ylabel(app.axeFFT, 'Y')
            zlabel(app.axeFFT, 'Z')
            app.axeFase = uiaxes(app.UIFigure);
            title(app.axeFase, 'Fase')
            xlabel(app.axeFase, 'X')
            ylabel(app.axeFase, 'Y')
            zlabel(app.axeFase, 'Z')
            app.SealesLabel = uilabel(app.UIFigure);
            app.SealesLabel.Text = 'Señales';
            app.AmplitudLabel = uilabel(app.UIFigure);
            app.AmplitudLabel.Text = 'Amplitud';
            app.amplitud = uispinner(app.UIFigure);
            app.amplitud.Step = 0.5;
            app.amplitud.Limits = [-10 10];
            app.amplitud.ValueChangedFcn = createCallbackFcn(app, @amplitudValueChanged, true);
            app.amplitud.Value = 2;
            app.FrecuenciaLabel = uilabel(app.UIFigure);
            app.FrecuenciaLabel.Text = 'Frecuencia';
            app.frecuencia = uieditfield(app.UIFigure, 'numeric');
            app.frecuencia.Value = 125;
            app.HzLabel = uilabel(app.UIFigure);
            app.HzLabel.Text = 'Hz';
            app.DesfaseLabel = uilabel(app.UIFigure);
            app.DesfaseLabel.Text = 'Desfase';
            app.desfase = uieditfield(app.UIFigure, 'numeric');
            app.desfase.Limits = [0 360];
            app.desfase.Value = 45;
            app.gradosLabel = uilabel(app.UIFigure);
            app.gradosLabel.Text = 'grados';
            app.FrecuenciademuestreoLabel = uilabel(app.UIFigure);
            app.FrecuenciademuestreoLabel.Text = {'Frecuencia '; 'de muestreo'};
            app.fmuestreo = uieditfield(app.UIFigure, 'numeric');
            app.fmuestreo.Value = 1000;
            app.HzLabel_2 = uilabel(app.UIFigure);
            app.HzLabel_2.Text = 'Hz';
            app.TiempoLabel = uilabel(app.UIFigure);
            app.TiempoLabel.Text = 'Tiempo';
            app.tiempo = uieditfield(app.UIFigure, 'numeric');
            app.tiempo.Limits = [0 Inf];
            app.tiempo.Value = 10;
            app.milisegundosLabel = uilabel(app.UIFigure);
            app.milisegundosLabel.Text = 'milisegundos';
            app.ActualizarDatosButton = uibutton(app.UIFigure, 'push');
            app.ActualizarDatosButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.ActualizarDatosButton.Text = 'Actualizar Datos';
            app.CBseno = uicheckbox(app.UIFigure);
            app.CBseno.ValueChangedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.CBseno.Text = 'Seno';
            app.CBseno.Value = true;
            app.CBcoseno = uicheckbox(app.UIFigure);
            app.CBcoseno.ValueChangedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.CBcoseno.Text = 'Coseno';
            app.CBruidoSenales = uicheckbox(app.UIFigure);
            app.CBruidoSenales.ValueChangedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.CBruidoSenales.Text = 'Rudio';
            app.CBcuadrada = uicheckbox(app.UIFigure);
            app.CBcuadrada.ValueChangedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.CBcuadrada.Text = 'Cuadrada';
            app.CBtriangular = uicheckbox(app.UIFigure);
            app.CBtriangular.ValueChangedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.CBtriangular.Text = 'Triangular';
            app.CBsumaSenales = uicheckbox(app.UIFigure);
            app.CBsumaSenales.ValueChangedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.CBsumaSenales.Text = 'Suma de señales';
            app.FuncionesLabel = uilabel(app.UIFigure);
            app.FuncionesLabel.Text = 'Funciones';
            app.AccionesLabel = uilabel(app.UIFigure);
            app.AccionesLabel.Text = 'Acciones';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PRAC_1_Voz_Equipo2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end